classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProjektZespolowyLabel        matlab.ui.control.Label
        WyborscenyPanel              matlab.ui.container.Panel
        WybierzfilmDropDownLabel     matlab.ui.control.Label
        WybierzfilmDropDown          matlab.ui.control.DropDown
        WybierzsceneDropDownLabel    matlab.ui.control.Label
        WybierzsceneDropDown         matlab.ui.control.DropDown
        WybierzreklameDropDownLabel  matlab.ui.control.Label
        WybierzreklameDropDown       matlab.ui.control.DropDown
        AutorzyButton                matlab.ui.control.Button
        ZaadujButton                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        WczytajplikwynikowyeksperymentuPanel  matlab.ui.container.Panel
        WybierzplikButton            matlab.ui.control.Button
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        file;
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZaadujButton
        function ZaadujButtonPushed(app, event)
            %delete(app)
            %hWin1 = app
            app2
            
        end

        % Button pushed function: WybierzplikButton
        function WybierzplikButtonPushed(app, event)
            global archivo
             [ filename, pathname ] = uigetfile({'*.csv'},'File Selector');
             fullpathname = strcat(pathname,filename);
             archivo = xlsread(fullpathname);
             %Pobrano nazwe pliku
             app.Label.Text = filename;
             app.file = filename;
             
             %app.UITable.Data = array2table(archivo);
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjektZespolowyLabel = uilabel(app.UIFigure);
            app.ProjektZespolowyLabel.Text = 'Projekt Zespolowy';
            app.WyborscenyPanel = uipanel(app.UIFigure);
            app.WyborscenyPanel.Title = 'Wybor sceny';
            app.WybierzfilmDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzfilmDropDownLabel.Text = 'Wybierz film';
            app.WybierzfilmDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzfilmDropDown.Items = {'Film 1', 'Film 2', 'Film 3', 'Film 4'};
            app.WybierzfilmDropDown.Value = 'Film 1';
            app.WybierzsceneDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzsceneDropDownLabel.Text = 'Wybierz scene';
            app.WybierzsceneDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzsceneDropDown.Items = {'Scena 1', 'Scena 2', 'Scena 3', 'Scena 4'};
            app.WybierzsceneDropDown.Value = 'Scena 1';
            app.WybierzreklameDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzreklameDropDownLabel.Text = 'Wybierz  reklame';
            app.WybierzreklameDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzreklameDropDown.Items = {'Reklama 1', 'Reklama 2', 'Reklama 3', 'Reklama 4'};
            app.WybierzreklameDropDown.Value = 'Reklama 1';
            app.AutorzyButton = uibutton(app.UIFigure, 'push');
            app.AutorzyButton.Text = 'Autorzy';
            app.ZaadujButton = uibutton(app.UIFigure, 'push');
            app.ZaadujButton.ButtonPushedFcn = createCallbackFcn(app, @ZaadujButtonPushed, true);
            app.ZaadujButton.Text = 'Załaduj';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'b.jpg';
            app.WczytajplikwynikowyeksperymentuPanel = uipanel(app.UIFigure);
            app.WczytajplikwynikowyeksperymentuPanel.Title = 'Wczytaj plik wynikowy eksperymentu';
            app.WybierzplikButton = uibutton(app.WczytajplikwynikowyeksperymentuPanel, 'push');
            app.WybierzplikButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzplikButtonPushed, true);
            app.WybierzplikButton.Text = 'Wybierz plik';
            app.Label = uilabel(app.WczytajplikwynikowyeksperymentuPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end