classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WybranascenaLabel              matlab.ui.control.Label
        WybierzinnasceneButton         matlab.ui.control.Button
        MapacieplnaButton              matlab.ui.control.Button
        MapafiksacjiButton             matlab.ui.control.Button
        WybierzAOIPanel                matlab.ui.container.Panel
        ZegarCheckBox                  matlab.ui.control.CheckBox
        FilmCheckBox_2                 matlab.ui.control.CheckBox
        ReklamaCheckBox                matlab.ui.control.CheckBox
        ZaladujAOIButton               matlab.ui.control.Button
        TestwybranychAOITextAreaLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        TestwybranychAOITextArea       matlab.ui.control.TextArea
        WczytajplikButton              matlab.ui.control.Button
        NazwaplikuLabel                matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        file
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WybierzinnasceneButton
        function WybierzinnasceneButtonPushed(app, event)
            delete(app)
            app1
            
        end

        % Button pushed function: ZaladujAOIButton
        function ZaladujAOIButtonPushed(app, event)

            value_film = get(app.FilmCheckBox_2, 'Value');
            value_reklama = get(app.ReklamaCheckBox, 'Value');
            value_zegar = get(app.ZegarCheckBox, 'Value');
            
            answer = num2str(value_reklama);
            
            if (value_reklama == 1 && value_zegar == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Zegar';
            elseif (value_reklama == 1 && value_zegar == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Film';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama, Film i Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar i Film';
            elseif (value_film == 1 && value_reklama == 0 && value_zegar == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Film';
        
            end
        end

        % Button pushed function: MapacieplnaButton
        function MapacieplnaButtonPushed(app, event)
            %Skrypt do mapy cieplnej
            %run(mapacieplna)
            
            %----------------------Script do mapy cieplnej-----------------
            obrazek_kobra = imread('Kobra-krolewska.jpg');
            %obrazek_rodzina = imread('rodzina.jpg');
            obrazek = obrazek_kobra;
            %obrazek = obrazek_rodzina
            %fixations = readtable('User0_fixations.csv');
            
            fixations = readtable(app.file);
            img_height = size(obrazek,1);
            img_width = size(obrazek,2);
            %fix=fixations(1:15,[6,7,9]); %dla rodziny
            fix=fixations(16:end,[6,7,9]); %dla kobry
            xd = table2array(fix);

            xd(:,1) = xd(:,1)*img_height;
            xd(:,2) = xd(:,2)*img_width;
            longest_fix = max(xd(:,3)); 
            % fixations(:,5) -czas fiksacji
            HM = uint8(zeros(img_height,img_width,1));
            temp = uint8(zeros(img_height,img_width,1));
            for j = 1:size(xd,1)
                a = floor((xd(j,3)*255)/longest_fix);
                color_new = [a,a,a];
                HM  = HM + imgaussfilt(insertShape(temp,'FilledCircle',[xd(j,2) xd(j,1) 40],'Color', color_new,'Opacity',1.0),10);
            end
            HM = double(HM(:,:,1));
            HM = HM/max(max(HM));

            alpha = HM;
            alpha2 = HM;
            alpha2(HM>0)=1;

            HM2 = HM.*255;
            HM2 = floor(HM2);
            HM2 = uint8(HM2);
            rgbImage = ind2rgb(HM2, jet(256));

            beta = ones(size(alpha2));
            beta = beta-alpha2;
            BG2 = (double(obrazek)./255);
            f = rgbImage .* alpha2;
            b = BG2 .* beta;
            rgbImage2 = f + b;
            
            %app.UIAxes = uiaxes(app.UIFigure);
            % Fill figure with axes and remove tick labels-na caly ekran
            %app.UIAxes.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, 'Mapa cieplna');
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            delete(app.Image);
            imshow(rgbImage2,'Parent', app.UIAxes);
            %------------------Koniec scriptu do mapy cieplnej---------
        end

        % Button pushed function: WczytajplikButton
        function WczytajplikButtonPushed(app, event)
            global archivo
             [ filename, pathname ] = uigetfile({'*.csv'},'File Selector');
             fullpathname = strcat(pathname,filename);
             archivo = xlsread(fullpathname);
             %Pobrano nazwe pliku
             app.NazwaplikuLabel.Text = filename;
             app.file = filename;
             %app.UITable.Data = array2table(archivo);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WybranascenaLabel = uilabel(app.UIFigure);
            app.WybranascenaLabel.Text = 'Wybrana scena';
            app.WybierzinnasceneButton = uibutton(app.UIFigure, 'push');
            app.WybierzinnasceneButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzinnasceneButtonPushed, true);
            app.WybierzinnasceneButton.Text = 'Wybierz inna scene';
            app.MapacieplnaButton = uibutton(app.UIFigure, 'push');
            app.MapacieplnaButton.ButtonPushedFcn = createCallbackFcn(app, @MapacieplnaButtonPushed, true);
            app.MapacieplnaButton.Text = 'Mapa cieplna';
            app.MapafiksacjiButton = uibutton(app.UIFigure, 'push');
            app.MapafiksacjiButton.Text = 'Mapa fiksacji';
            app.WybierzAOIPanel = uipanel(app.UIFigure);
            app.WybierzAOIPanel.Title = 'Wybierz AOI';
            app.ZegarCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ZegarCheckBox.Text = 'Zegar';
            app.FilmCheckBox_2 = uicheckbox(app.WybierzAOIPanel);
            app.FilmCheckBox_2.Text = 'Film';
            app.ReklamaCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ReklamaCheckBox.Text = 'Reklama';
            app.ZaladujAOIButton = uibutton(app.UIFigure, 'push');
            app.ZaladujAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ZaladujAOIButtonPushed, true);
            app.ZaladujAOIButton.Text = 'Zaladuj AOI';
            app.TestwybranychAOITextAreaLabel = uilabel(app.UIFigure);
            app.TestwybranychAOITextAreaLabel.Text = 'Test wybranych AOI';
            app.TestwybranychAOITextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.WczytajplikButton = uibutton(app.UIFigure, 'push');
            app.WczytajplikButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajplikButtonPushed, true);
            app.WczytajplikButton.Text = 'Wczytaj plik';
            app.NazwaplikuLabel = uilabel(app.UIFigure);
            app.NazwaplikuLabel.Text = 'Nazwa pliku...';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Kobra-krolewska.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end